classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveasMenu                     matlab.ui.container.Menu
        ExportmelodyMenu               matlab.ui.container.Menu
        WAVMenu                        matlab.ui.container.Menu
        PlayAllButton                  matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        WindowFunctionTab              matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        plotWindowFunction             matlab.ui.control.UIAxes
        InfoTab                        matlab.ui.container.Tab
        DurationLabel                  matlab.ui.control.Label
        CellvaluesLabel                matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        InstrumentsTab                 matlab.ui.container.Tab
        sLabel                         matlab.ui.control.Label
        timeLabel                      matlab.ui.control.Label
        MelodyLengthLabel              matlab.ui.control.Label
        MelodyToneCountEditField       matlab.ui.control.NumericEditField
        MelodyToneCountEditFieldLabel  matlab.ui.control.Label
        InstrumentsTable               matlab.ui.control.Table
        PianoTab                       matlab.ui.container.Tab
        PlayPianoButton                matlab.ui.control.Button
        PianoTable                     matlab.ui.control.Table
        GuitarTab                      matlab.ui.container.Tab
        PlayGuitarButton               matlab.ui.control.Button
        GuitarTable                    matlab.ui.control.Table
    end


    properties (Access = private)
        windowFunction WindowFunction % Description
        guitar Instruments % Description
        piano Instruments
        numberCols int32
        pianoRows int32
        guitarRows int32
    end

    methods (Access = private)

        function initializeTables(app)
            app.InstrumentsTable.ColumnName = 1 : app.numberCols;
            app.InstrumentsTable.RowName = [{'Duration'; 'Piano'; 'Guitar'}];

            disp("Here");
            disp(app.InstrumentsTable.RowName);
            app.InstrumentsTable.Data = zeros(3, app.numberCols);
            for i = 1 : app.numberCols
                app.InstrumentsTable.Data(1, i) = 0.3;
            end

            ncols = size(app.InstrumentsTable.Data,2);
            app.InstrumentsTable.ColumnEditable = true(1,ncols); %all columns editable


            app.PianoTable.ColumnName = 1 : app.numberCols;
            app.PianoTable.RowName = ['Duration' app.piano.toneNames];

            disp("Here");
            disp(app.PianoTable.RowName);
            app.PianoTable.Data = zeros(app.pianoRows, app.numberCols);
            for i = 1 : app.numberCols
                app.PianoTable.Data(1, i) = 0.3;
            end

            ncols = size(app.PianoTable.Data,2);
            app.PianoTable.ColumnEditable = true(1,ncols); %all columns editable


            app.GuitarTable.ColumnName = 1 : app.numberCols;
            app.GuitarTable.RowName = ['Duration' app.guitar.toneNames];
            app.GuitarTable.Data = zeros(app.guitarRows, app.numberCols);
            for i = 1 : app.numberCols
                app.GuitarTable.Data(1, i) = 0.3;
            end

            ncols = size(app.GuitarTable.Data,2);
            app.GuitarTable.ColumnEditable = true(1,ncols); %all columns editable
            updateMelodyLength(app);
        end
        %% Windowfunctionsetup
        function setupWindowfunction(app)
            app.UITable.Data = zeros(5, 2);
            processingAmpVec   = [0.0  1.0  0.9  0.7  0.0];
            processingTimeVec  = [0.0  0.1  0.3  0.9  1.0];
            app.UITable.Data = [0.0 0.0];
            app.windowFunction = WindowFunction(processingAmpVec, processingTimeVec);
            [xVec, yVec] = app.windowFunction.calcWindowFunction(11025);
            plot(app.plotWindowFunction, xVec, yVec);
            for i = 2 : length(processingAmpVec)
                app.UITable.Data = [app.UITable.Data; processingTimeVec(i) processingAmpVec(i)]
            end
            % Set Editable
            ncols = size(app.UITable.Data,2);
            app.UITable.ColumnEditable = true(1,ncols); %all columns editable
        end
        
        function color = getColor(app, indices, data)
             if (data == 0)
                    if (~(mod(indices(1), 2) == 0))
                        color = uistyle('BackgroundColor','white');
                    else
                        color = uistyle('BackgroundColor',[0.94,0.94,0.94]);
                    end
                else
                    color = uistyle('BackgroundColor',[0.9,1.0,(1.0-data)]);
             end
        end
        
        function updateMelodyLength(app)   
            display("getMelodyLength");
            duration = 0;
            for i = 1 : app.numberCols
                duration = duration + app.InstrumentsTable.Data(1, i);
            end
            app.timeLabel.Text = string(duration);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.numberCols = app.MelodyToneCountEditField.Value;
            app.setupWindowfunction();
            %% Guitartable setup
            app.guitar = Instruments("guitar");
            app.guitarRows = length(app.guitar.toneNames) + 1;
            %% Pianotable setup
            app.piano = Instruments("piano");
            app.pianoRows = length(app.piano.toneNames) + 1;
            app.initializeTables();
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            if (event.Indices(2) == 1)
                app.windowFunction.relTimeVec(event.Indices(1)) = event.NewData;
            else
                app.windowFunction.relAmpVec(event.Indices(1)) = event.NewData;
            end
            [xVec, yVec] = app.windowFunction.calcWindowFunction(11025);
            plot(app.plotWindowFunction, xVec, yVec);
        end

        % Cell edit callback: PianoTable
        function PianoTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            display(event);
            if(~(indices(1) == 1))
                if (newData > 1)
                    app.PianoTable.Data(indices(1), indices(2)) = 1;
                    newData = 1.0;
                end
                s = getColor(app, indices, newData);
                addStyle(app.PianoTable,s,'cell',indices);

                instData = max(app.PianoTable.Data(2:app.pianoRows, indices(2)))
                app.InstrumentsTable.Data(2, indices(2)) = instData;
                sI = getColor(app, [2, indices(2)], instData);
                addStyle(app.InstrumentsTable,sI,'cell',[2, indices(2)]);
            else
                app.GuitarTable.Data(indices(1), indices(2)) = newData;
                app.InstrumentsTable.Data(indices(1), indices(2)) = newData;
                updateMelodyLength(app);
            end
        end

        % Cell edit callback: GuitarTable
        function GuitarTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            display(event);
            if(~(indices(1) == 1))
                if (newData > 1)
                    app.GuitarTable.Data(indices(1), indices(2)) = 1;
                    newData = 1.0;
                end
                s = getColor(app, indices, newData);
                addStyle(app.GuitarTable,s,'cell',indices);

                instData = max(app.GuitarTable.Data(2:app.guitarRows, indices(2)))
                app.InstrumentsTable.Data(3, indices(2)) = instData;
                sI = getColor(app, [3, indices(2)], instData);
                addStyle(app.InstrumentsTable,sI,'cell',[3, indices(2)]);
            else
                app.PianoTable.Data(indices(1), indices(2)) = newData;
                app.InstrumentsTable.Data(indices(1), indices(2)) = newData;
                updateMelodyLength(app);
            end
        end

        % Button pushed function: PlayAllButton
        function PlayAllButtonPushed(app, event)

        end

        % Button pushed function: PlayGuitarButton
        function PlayGuitarButtonPushed(app, event)
            numberRows = length(app.guitar.toneNames);
            melodyTone = EmptyTone(11025);

            for i = 1 : app.numberCols
                tones = [];
                duration = app.GuitarTable.Data(1, i);
                for j = 2 : numberRows
                    if ~(app.GuitarTable.Data(j, i) == 0)
                        savedAmpVector = app.guitar.scale(j).ampVector;
                        app.guitar.scale(j).ampVector = app.GuitarTable.Data(j, i) .* savedAmpVector;
                        tones = [tones app.guitar.scale(j)];
                        app.guitar.scale(j).ampVector = savedAmpVector;
                    end
                end

                if(length(tones) > 1)
                    newTone = tones(1);
                    tones(1) = [];
                    newTone = newTone.interferSounds(tones);
                    newTone.ampVector = newTone.ampVector .\ max(newTone.ampVector);
                    melodyTone = melodyTone.concatTone(newTone);
                else
                    melodyTone = melodyTone.concatTone(tones);
                end
            end
            melodyTone.play();
        end

        % Button pushed function: PlayPianoButton
        function PlayPianoButtonPushed(app, event)
            numberRows = length(app.piano.toneNames);
            melodyTone = EmptyTone(11025);

            for i = 1 : app.numberCols
                tones = [];
                for j = 2 : numberRows
                    if ~(app.PianoTable.Data(j, i) == 0)
                        savedAmpVector = app.piano.scale(j).ampVector;
                        app.piano.scale(j).ampVector = app.PianoTable.Data(j, i) .* savedAmpVector;
                        tones = [tones app.piano.scale(j)];
                        app.piano.scale(j).ampVector = savedAmpVector;
                    end
                end

                if(length(tones) > 1)
                    newTone = tones(1);
                    tones(1) = [];
                    newTone = newTone.interferSounds(tones);
                    melodyTone = melodyTone.concatTone(newTone);
                else
                    melodyTone = melodyTone.concatTone(tones);
                end
            end
            melodyTone.play();
        end

        % Value changed function: MelodyToneCountEditField
        function MelodyToneCountEditFieldValueChanged(app, event)
            app.numberCols = app.MelodyToneCountEditField.Value;
            app.initializeTables();
        end

        % Cell edit callback: InstrumentsTable
        function InstrumentsTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            display(event);
            if(~(indices(1) == 1))
                app.InstrumentsTable.Data(indices(1), indices(2)) = event.PreviousData;
            else
                app.PianoTable.Data(indices(1), indices(2)) = newData;
                app.GuitarTable.Data(indices(1), indices(2)) = newData;
                updateMelodyLength(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Text = 'Save as';
            app.ExportmelodyMenu = uimenu(app.FileMenu);
            app.ExportmelodyMenu.Separator = 'on';
            app.ExportmelodyMenu.Text = 'Export melody';
            app.WAVMenu = uimenu(app.ExportmelodyMenu);
            app.WAVMenu.Text = 'WAV';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WindowFunctionTab = uitab(app.TabGroup);
            app.WindowFunctionTab.Title = 'Window Function';
            app.plotWindowFunction = uiaxes(app.WindowFunctionTab);
            title(app.plotWindowFunction, 'Relative Window Function')
            xlabel(app.plotWindowFunction, 'Realtive Time / %')
            ylabel(app.plotWindowFunction, 'Relative Amplitude / %')
            zlabel(app.plotWindowFunction, 'Z')
            app.UITable = uitable(app.WindowFunctionTab);
            app.UITable.ColumnName = {'Relative Time'; 'Amplitude'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.Label = uilabel(app.InfoTab);
            app.Label.Text = 'The Duration is the same in the Instruments, Piano and Guitar tabs, since they refer to the same melody track. If you edit it in one of these tables, it will be adjusted in the others.';
            app.Label_2 = uilabel(app.InfoTab);
            app.Label_2.Text = {'In Instruments, Piano and Guitar the first row describes the duration in seconds.'; 'Further lines in Piano and Guitar describe the volume of the notes with values between 0.0 - 1.0.'; 'In Instruments, the values in the further lines represent the volume of the loudest tone for the two instruments.'};
            app.CellvaluesLabel = uilabel(app.InfoTab);
            app.CellvaluesLabel.Text = 'Cell values';
            app.DurationLabel = uilabel(app.InfoTab);
            app.DurationLabel.Text = 'Duration';
            app.InstrumentsTab = uitab(app.TabGroup);
            app.InstrumentsTab.Title = 'Instruments';
            app.InstrumentsTable = uitable(app.InstrumentsTab);
            app.InstrumentsTable.ColumnName = {'T1'};
            app.InstrumentsTable.RowName = {'Duration, Piano,Guitar'};
            app.InstrumentsTable.CellEditCallback = createCallbackFcn(app, @InstrumentsTableCellEdit, true);
            app.MelodyToneCountEditFieldLabel = uilabel(app.InstrumentsTab);
            app.MelodyToneCountEditFieldLabel.Text = 'Tonecount';
            app.MelodyToneCountEditField = uieditfield(app.InstrumentsTab, 'numeric');
            app.MelodyToneCountEditField.ValueChangedFcn = createCallbackFcn(app, @MelodyToneCountEditFieldValueChanged, true);
            app.MelodyToneCountEditField.Value = 10;
            app.MelodyLengthLabel = uilabel(app.InstrumentsTab);
            app.MelodyLengthLabel.Text = 'Melody Length';
            app.timeLabel = uilabel(app.InstrumentsTab);
            app.timeLabel.Text = 'time';
            app.sLabel = uilabel(app.InstrumentsTab);
            app.sLabel.Text = 's';
            app.PianoTab = uitab(app.TabGroup);
            app.PianoTab.Title = 'Piano';
            app.PianoTable = uitable(app.PianoTab);
            app.PianoTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PianoTable.RowName = {};
            app.PianoTable.CellEditCallback = createCallbackFcn(app, @PianoTableCellEdit, true);
            app.PlayPianoButton = uibutton(app.PianoTab, 'push');
            app.PlayPianoButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPianoButtonPushed, true);
            app.PlayPianoButton.Text = 'Play Piano';
            app.GuitarTab = uitab(app.TabGroup);
            app.GuitarTab.Title = 'Guitar';
            app.GuitarTable = uitable(app.GuitarTab);
            app.GuitarTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.GuitarTable.RowName = {};
            app.GuitarTable.CellEditCallback = createCallbackFcn(app, @GuitarTableCellEdit, true);
            app.PlayGuitarButton = uibutton(app.GuitarTab, 'push');
            app.PlayGuitarButton.ButtonPushedFcn = createCallbackFcn(app, @PlayGuitarButtonPushed, true);
            app.PlayGuitarButton.Text = 'Play Guitar';
            app.PlayAllButton = uibutton(app.UIFigure, 'push');
            app.PlayAllButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAllButtonPushed, true);
            app.PlayAllButton.Text = 'Play All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end